classdef autoLibGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChooseStartingDirButton         matlab.ui.control.Button
        ChooseEndingDirButton           matlab.ui.control.Button
        startDirField                   matlab.ui.control.EditField
        endDirField                     matlab.ui.control.EditField
        BeamTrawlButton                 matlab.ui.control.Button
        CompileDataLibandRunInfoTableButton  matlab.ui.control.Button
        ChooseSaveDirButton             matlab.ui.control.Button
        saveDirField                    matlab.ui.control.EditField
        LoadingProgressGauge            matlab.ui.control.Gauge
        ChooseDataDirectoryButton       matlab.ui.control.Button
        dataDirField                    matlab.ui.control.EditField
        selectedTable                   matlab.ui.control.Table
        doneField                       matlab.ui.control.EditField
        AutomaticDataLibraryCreationToolLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SwitchAllHighlightedEntriesButton  matlab.ui.control.Button
        IncludeVarsCSVspaceokLabel      matlab.ui.control.Label
        IncludeVarsArea                 matlab.ui.control.TextArea
        ExcludeVarsCSVspaceokLabel      matlab.ui.control.Label
        ExcludeVarsArea                 matlab.ui.control.TextArea
        DataLibraryDescriptionTextAreaLabel  matlab.ui.control.Label
        DataLibraryDescriptionTextArea  matlab.ui.control.TextArea
        NonCiceroVarsCSVspaceokLabel    matlab.ui.control.Label
        ncVarsArea                      matlab.ui.control.TextArea
        LoadText                        matlab.ui.control.TextArea
        ExcludeButton                   matlab.ui.control.StateButton
        optionalChooseanexistingCSVButton  matlab.ui.control.Button
        csvDirBox                       matlab.ui.control.TextArea
        bypassCSVGenCheck               matlab.ui.control.CheckBox
        csvCheckboxLabel                matlab.ui.control.Label
        DataFilenamenoperiodsLabel      matlab.ui.control.Label
        DataFilenameBox                 matlab.ui.control.EditField
        TouseanexistingCSVyouneedtoperformsteps15Altthen6Label  matlab.ui.control.Label
        CompileONLYRunInfoTableButton   matlab.ui.control.Button
        IfyouneedtospecifyvarsLeaveasisifyouarentsureLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        table_selected % Description
    end
    
    methods (Access = public)
        
        function [datestr] = getDatestr(app,directory) 
            %extract the matlab datestring object from the starting
            %directories. NOTE: Relies on the current datescheme we have in the
            %citadel. Like so: F:\StrontiumData\2019\2019.12\12.16
            Year = str2num(directory(length(directory)-12:length(directory)-9));
            Month = str2num(directory(length(directory)-4:length(directory)-3));
            Day = str2num(directory(length(directory)-1:length(directory)));
        
            datestr = datetime(Year,Month,Day);
        end
        
        function [directory]  = getDirectory(app,datein,base_directory)
            %basically just the inverse of the above function, where I want to
            %output the string form of the directory given a datestring input
            %and the base_directory, which will change depending on which
            %computer this is run off of. 
            y = num2str(year(datein),'%04d');
            m = num2str(month(datein),'%02d');
            d = num2str(day(datein),'%02d');
            
        
            directory = string([base_directory filesep y filesep y '.' m filesep m '.' d]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseDataDirectoryButton
        function ChooseDataDirectoryButtonPushed(app, event)
            %gets the location of say SrData on the user's computer
            the_gotten_dir = uigetdir('Choose the data directory (StrontiumData or LithiumData).');
            if the_gotten_dir == 0
                app.dataDirField.Value = "";
            else
                app.dataDirField.Value = the_gotten_dir;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ChooseStartingDirButton
        function ChooseStartingDirButtonPushed(app, event)
            %choose starting day
            the_gotten_dir = uigetdir(app.dataDirField.Value,'Choose the starting day folder.');
            if the_gotten_dir == 0
                app.startDirField.Value = "";
            else
                app.startDirField.Value = the_gotten_dir;
                
                % Now choose the ending directory.
                ChooseEndingDirButtonPushed(app,event);
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ChooseEndingDirButton
        function ChooseEndingDirButtonPushed(app, event)
            %choose ending day
            the_gotten_dir = uigetdir(app.dataDirField.Value,'Choose the last day folder.');
            if the_gotten_dir == 0
                app.endDirField.Value = "";
            else
                app.endDirField.Value = the_gotten_dir;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: BeamTrawlButton
        function BeamTrawlButtonPushed(app, event)
            %get datestr object MATLAB (nice because doing +1 increments the day
            %without having to worry about getting months and years right)
            starting_directory = app.dataDirField.Value;
            tInit = getDatestr(app,app.startDirField.Value);
            tFin = getDatestr(app,app.endDirField.Value);
            %For each day get the collection of runs in that folder
            Tarray = tInit:tFin;
%             string()
            run_titles = string([]);
            run_dates = NaT(0);
            
            app.LoadText.Value = "Loading run folders...";
            
            for ii = Tarray
                %getting all of the files and subdirectories that are part of the
                %current directory
                subdirs = dir(getDirectory(app,ii,starting_directory));
                %then, using logical indexing, only take the ones that are actually
                %subdirectories
                ok_ones = logical([subdirs.isdir].*[~strcmp({subdirs.name},'.')].*[~strcmp({subdirs.name},'..')]);
                subdirs = subdirs(ok_ones);
                %append the run directory titles into the run_titles matrix. This is
                %going to be output as a CSV a little later in the process. Note I have
                %to do a bit of gymnastics with cells here to make sure that the
                %character arrays aren't concatenated before they are written into the
                %run_titles string array. 
                s = size(run_titles);
            %     keyboard;
                run_titles(1:(length(subdirs)+1),s(2)+1) = string([getDirectory(app,ii,starting_directory) string({subdirs.name})]);
                
                run_dates(1:(length(subdirs)+1),s(2)+1) = ii;
                app.LoadingProgressGauge.Value = 100*(find(Tarray==ii)/length(Tarray));
                drawnow;
            end
            
            app.LoadText.Value = "Checking if atomdatas exist...";
            
            atomdata_exists_bool = zeros( size(run_titles) );
            
            N = size(run_titles,2);
            for k = 1:N
                these_run_folders = fullfile(run_titles(1,k), run_titles(:,k));
                tb = arrayfun(@(x) checkIfAtomDataExistsBoolean(x), these_run_folders );
                atomdata_exists_bool(1:length(tb), k) = tb;
                app.LoadingProgressGauge.Value = 100*( k / N );
                drawnow;
            end
            
            app.LoadText.Value = "Done! Select the desired runs.";
            
            run_titles = run_titles(2:end,1:end);
            run_dates = run_dates(2:end,1:end);
            atomdata_exists_bool = atomdata_exists_bool(2:end,:);
            
            %remove missing entries (these are there since not all days have the same
            %amount of runs taken.
            log_ind = ~run_titles.ismissing;
            run_titles = run_titles(log_ind);
            run_dates = run_dates(log_ind);
            atomdata_exists_bool = atomdata_exists_bool(log_ind);
            
            if app.ExcludeButton.Value
                log_ind2 = logical(atomdata_exists_bool);
                run_titles = run_titles(log_ind2);
                run_dates = run_dates(log_ind2);
                atomdata_exists_bool = atomdata_exists_bool(log_ind2);
            end
            
            run_dates.Format = 'dd-MMM-yyyy';
            include_bool = false(size(run_titles));
            
            %make a table object containing run information, and the option
            %to exclude certain runs
            tab = table(atomdata_exists_bool,run_titles,run_dates,include_bool);
            app.selectedTable.Data = tab;
        end

        % Button pushed function: ChooseSaveDirButton
        function ChooseSaveDirButtonPushed(app, event)
            %choose where to save the runDataInfo
            the_gotten_dir = uigetdir(app.dataDirField.Value,'Choose the data/csv save directory.');
            
            if the_gotten_dir == 0
                app.saveDirField.Value = "";
            else
                app.saveDirField.Value = the_gotten_dir;
            end
            
            figure(app.UIFigure);
        end

        % Button pushed function: 
        % CompileDataLibandRunInfoTableButton
        function CompileDataLibandRunInfoTableButtonPushed(app, event)
            
            if ~app.bypassCSVGenCheck.Value
                %from the uitable, extract the run info for the allowed runs
                tab = app.selectedTable.Data;
                %get the datetime list
                run_dates = tab.run_dates(tab.include_bool);
                %extract year, month day 
                year_col = year(run_dates);
                month_col = month(run_dates);
                day_col = day(run_dates);
                comments = strings(size(run_dates));
                %extract titles
                run_titles = tab.run_titles(tab.include_bool);
                seriesID = strings(size(run_titles));
                runType = strings(size(run_titles));
                
                %the time range
                tInit = getDatestr(app,app.startDirField.Value);
                tFin = getDatestr(app,app.endDirField.Value);
                
                %get the data in outputabble form
                nc_vars = split(strrep(app.ncVarsArea.Value,' ',''),',');
                %correct for no entry or extra commas
                nc_vars = nc_vars(nc_vars ~= "");
                %create columns and headers for CSV
                titles = ["Year" , "Month", "Day","SeriesID","RunType","RunFolder", nc_vars',"Comments"];
                columns = [year_col,month_col,day_col,seriesID,runType,run_titles,strings(size(run_titles,1),size(nc_vars,1)),comments];
                %combine the titles and columns for output
                csv_output = [titles;columns];
                %save the output in datamanager format
                csv_dir = strcat(app.saveDirField.Value,filesep,string(tInit),'_',string(tFin),'RunInfoTable.csv');
                writematrix(csv_output,csv_dir);
                
                alt_filename = strcat('data_from_',string(tInit),'_',string(tFin),'.mat');
                num_columns = length(titles);
            else
                csv_dir = app.csvDirBox.Value{1};
                alt_filename = strcat('data_compiled_on_',date,'.mat');
                num_columns = size(readmatrix(csv_dir,'OutputType','string','Delimiter',','),2);
            end
            
            if app.DataFilenameBox.Value ~= "leave_as_is_to_keep_default_format.mat"
                data_filename = app.DataFilenameBox.Value;
            else
                data_filename = alt_filename;
            end
            
            
            %package the required information for GetRunData
            exclude_vars = split(strrep(app.ExcludeVarsArea.Value,' ',''),',');
            include_vars = split(strrep(app.IncludeVarsArea.Value,' ',''),',');
            %get the data in outputabble form
            nc_vars = split(strrep(app.ncVarsArea.Value,' ',''),',');
            %correct for no entry or extra commas
            nc_vars = nc_vars(nc_vars ~= "");
            if (length(nc_vars) ~= num_columns -7) 
                %make sure that the user specifies the right number of
                %ncVars
                app.doneField.Value = ['CSV has ' num2str(num_columns-7) 'extra cols, but '...
                    num2str(length(nc_vars)) ' ncVars were specified.'];
            else
                GetRunData(csv_dir,'NumColumns',num_columns,"DataDir",app.dataDirField.Value,...
                    "DataLibraryDescription",app.DataLibraryDescriptionTextArea.Value,...
                    "DataFileName", data_filename...
                    ,"excludeVars",exclude_vars,"includeVars",include_vars...
                    ,"ncVars",nc_vars,"OpenOutputFolder",true,"OutputDir",app.saveDirField.Value);
                
                
                %say that it's done
                app.doneField.Value = 'Done!';
            end
            
        end

        % Cell selection callback: selectedTable
        function selectedTableCellSelection(app, event)
            indices = event.Indices;
            
            app.table_selected = indices;
        end

        % Button pushed function: SwitchAllHighlightedEntriesButton
        function SwitchAllHighlightedEntriesButtonPushed(app, event)
            curr_bool = app.selectedTable.Data{app.table_selected(1,1),4};
            new_bool = not(curr_bool);
            
            for ii = 1:size(app.table_selected,1)
                app.selectedTable.Data{app.table_selected(ii,1),4} = new_bool;
            end
        end

        % Button pushed function: optionalChooseanexistingCSVButton
        function chooseExistingCSV(app, event)
            [fname,fpath] = uigetfile(fullfile(app.saveDirField.Value,'*.csv'),'Choose the RunInfoTable CSV.');
            if fname == 0
                app.csvDirBox.Value = "";
            else
                app.csvDirBox.Value = fullfile(fpath,fname);
                %extract the nc_vars, assuming that they are everything in
                %there that isn't part of the standard CSV headers
                headers = string(readtable(fullfile(fpath,fname),'Delimiter',',').Properties.VariableNames);
                regular = ["Year" , "Month", "Day","SeriesID","RunType","RunFolder","Comments"];
                nc_vars = setdiff(headers,regular);
                if (~isempty(nc_vars))
                    conced = sprintf("%s,",nc_vars);
                    app.ncVarsArea.Value = eraseBetween(conced,strlength(conced),strlength(conced));
                end
            end
            figure(app.UIFigure);
        end

        % Button pushed function: CompileONLYRunInfoTableButton
        function CompileOnlyCSV(app, event)
            %from the uitable, extract the run info for the allowed runs
            tab = app.selectedTable.Data;
            %get the datetime list
            run_dates = tab.run_dates(tab.include_bool);
            %extract year, month day 
            year_col = year(run_dates);
            month_col = month(run_dates);
            day_col = day(run_dates);
            comments = strings(size(run_dates));
            %extract titles
            run_titles = tab.run_titles(tab.include_bool);
            seriesID = strings(size(run_titles));
            runType = strings(size(run_titles));
            
            %the time range
            tInit = getDatestr(app,app.startDirField.Value);
            tFin = getDatestr(app,app.endDirField.Value);
            
            %get the data in outputabble form
            %get the data in outputabble form
            nc_vars = split(strrep(app.ncVarsArea.Value,' ',''),',');
            %correct for no entry or extra commas
            nc_vars = nc_vars(nc_vars ~= "");
            %create the appropriate titles and columns of the CSV
            titles = ["Year" , "Month", "Day","SeriesID","RunType","RunFolder", nc_vars',"Comments"];
            columns = [year_col,month_col,day_col,seriesID,runType,run_titles,strings(size(run_titles,1),size(nc_vars,1)),comments];
            csv_output = [titles;columns];
            %save the output in datamanager format
            csv_dir = strcat(app.saveDirField.Value,filesep,string(tInit),'_',string(tFin),'_RunInfoTable.csv');
            writematrix(csv_output,csv_dir);
            
            app.doneField.Value = 'Done!';
        end

        % Value changed function: bypassCSVGenCheck
        function bypassCSVGenCheckValueChanged(app, event)
            value = app.bypassCSVGenCheck.Value;
            app.ncVarsArea.Editable = ~value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChooseStartingDirButton = uibutton(app.UIFigure, 'push');
            app.ChooseStartingDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseStartingDirButtonPushed, true);
            app.ChooseStartingDirButton.Text = '3: Choose Starting Day Dir';
            app.ChooseEndingDirButton = uibutton(app.UIFigure, 'push');
            app.ChooseEndingDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseEndingDirButtonPushed, true);
            app.ChooseEndingDirButton.Text = '4: Choose Ending Day Dir';
            app.startDirField = uieditfield(app.UIFigure, 'text');
            app.startDirField.Editable = 'off';
            app.endDirField = uieditfield(app.UIFigure, 'text');
            app.endDirField.Editable = 'off';
            app.BeamTrawlButton = uibutton(app.UIFigure, 'push');
            app.BeamTrawlButton.ButtonPushedFcn = createCallbackFcn(app, @BeamTrawlButtonPushed, true);
            app.BeamTrawlButton.Text = {'5: Beam '; '    Trawl!'};
            app.CompileDataLibandRunInfoTableButton = uibutton(app.UIFigure, 'push');
            app.CompileDataLibandRunInfoTableButton.ButtonPushedFcn = createCallbackFcn(app, @CompileDataLibandRunInfoTableButtonPushed, true);
            app.CompileDataLibandRunInfoTableButton.Text = '7: Compile DataLib and RunInfoTable';
            app.ChooseSaveDirButton = uibutton(app.UIFigure, 'push');
            app.ChooseSaveDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseSaveDirButtonPushed, true);
            app.ChooseSaveDirButton.Text = '1: Choose Save Dir';
            app.saveDirField = uieditfield(app.UIFigure, 'text');
            app.saveDirField.Editable = 'off';
            app.saveDirField.Value = 'Save directory will go here';
            app.LoadingProgressGauge = uigauge(app.UIFigure, 'circular');
            app.ChooseDataDirectoryButton = uibutton(app.UIFigure, 'push');
            app.ChooseDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataDirectoryButtonPushed, true);
            app.ChooseDataDirectoryButton.Text = {'2: Choose Data Directory'; '(i.e. StrontiumData)'};
            app.dataDirField = uieditfield(app.UIFigure, 'text');
            app.dataDirField.Editable = 'off';
            app.selectedTable = uitable(app.UIFigure);
            app.selectedTable.ColumnName = {'Atomdata?'; 'Run Title'; 'Date'; 'Include?'};
            app.selectedTable.RowName = {};
            app.selectedTable.ColumnEditable = [false false false true];
            app.selectedTable.CellSelectionCallback = createCallbackFcn(app, @selectedTableCellSelection, true);
            app.doneField = uieditfield(app.UIFigure, 'text');
            app.doneField.Editable = 'off';
            app.AutomaticDataLibraryCreationToolLabel = uilabel(app.UIFigure);
            app.AutomaticDataLibraryCreationToolLabel.Text = 'Automatic DataLibrary Creation Tool';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'This tool will help you easily collect the atomdata and create an info table for a set of runs of your choosing.'; 'The info table is used by dataManager and contains information about run parameters for readabillity.'; '(make sure that DataManager is added to your matlab path)'};
            app.SwitchAllHighlightedEntriesButton = uibutton(app.UIFigure, 'push');
            app.SwitchAllHighlightedEntriesButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchAllHighlightedEntriesButtonPushed, true);
            app.SwitchAllHighlightedEntriesButton.Text = {'Switch'; 'All  '; 'Highlighted'; 'Entries'};
            app.IncludeVarsCSVspaceokLabel = uilabel(app.UIFigure);
            app.IncludeVarsCSVspaceokLabel.Text = {'Include Vars'; '(CSV, space ok)'};
            app.IncludeVarsArea = uitextarea(app.UIFigure);
            app.IncludeVarsArea.Value = {'VVA1064_Er, VVA915_Er, LatticeHold'};
            app.ExcludeVarsCSVspaceokLabel = uilabel(app.UIFigure);
            app.ExcludeVarsCSVspaceokLabel.Text = {'Exclude Vars'; '(CSV, space ok)'};
            app.ExcludeVarsArea = uitextarea(app.UIFigure);
            app.ExcludeVarsArea.Value = {'IterationCount, IterationNum, ImageTime, LogTime'};
            app.DataLibraryDescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DataLibraryDescriptionTextAreaLabel.Text = {'Data Library'; 'Description'};
            app.DataLibraryDescriptionTextArea = uitextarea(app.UIFigure);
            app.DataLibraryDescriptionTextArea.Value = {'Insert description of data library.'};
            app.NonCiceroVarsCSVspaceokLabel = uilabel(app.UIFigure);
            app.NonCiceroVarsCSVspaceokLabel.Text = {'Non-Cicero Vars'; '(CSV, space ok)'};
            app.ncVarsArea = uitextarea(app.UIFigure);
            app.LoadText = uitextarea(app.UIFigure);
            app.ExcludeButton = uibutton(app.UIFigure, 'state');
            app.ExcludeButton.Text = {'Exclude Folders '; 'without Atomdata?'};
            app.optionalChooseanexistingCSVButton = uibutton(app.UIFigure, 'push');
            app.optionalChooseanexistingCSVButton.ButtonPushedFcn = createCallbackFcn(app, @chooseExistingCSV, true);
            app.optionalChooseanexistingCSVButton.Text = '0: (optional) Choose an existing CSV';
            app.csvDirBox = uitextarea(app.UIFigure);
            app.csvDirBox.Editable = 'off';
            app.csvDirBox.Value = {'Existing CSV directory will go here (if chosen)'};
            app.bypassCSVGenCheck = uicheckbox(app.UIFigure);
            app.bypassCSVGenCheck.ValueChangedFcn = createCallbackFcn(app, @bypassCSVGenCheckValueChanged, true);
            app.bypassCSVGenCheck.Text = '';
            app.csvCheckboxLabel = uilabel(app.UIFigure);
            app.csvCheckboxLabel.Text = 'Bypass CSV generation (Use existing CSV)';
            app.DataFilenamenoperiodsLabel = uilabel(app.UIFigure);
            app.DataFilenamenoperiodsLabel.Text = 'Data Filename (no periods!)';
            app.DataFilenameBox = uieditfield(app.UIFigure, 'text');
            app.DataFilenameBox.Value = 'leave_as_is_to_keep_default_format.mat';
            app.TouseanexistingCSVyouneedtoperformsteps15Altthen6Label = uilabel(app.UIFigure);
            app.TouseanexistingCSVyouneedtoperformsteps15Altthen6Label.Text = {'To use existing runInfoTable CSV, check the bypass box.'; 'If using existing CSV, skip steps 3-5.'};
            app.CompileONLYRunInfoTableButton = uibutton(app.UIFigure, 'push');
            app.CompileONLYRunInfoTableButton.ButtonPushedFcn = createCallbackFcn(app, @CompileOnlyCSV, true);
            app.CompileONLYRunInfoTableButton.Text = '7*: Compile ONLY RunInfoTable';
            app.IfyouneedtospecifyvarsLeaveasisifyouarentsureLabel = uilabel(app.UIFigure);
            app.IfyouneedtospecifyvarsLeaveasisifyouarentsureLabel.Text = '6: If you need to, specify vars. Leave as is if you aren''t sure.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'(If existing CSV is used, then ncVars field'; 'will be locked and filled from CSV)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autoLibGen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end