classdef auto_gen_info < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ChooseStartingDirButton    matlab.ui.control.Button
        ChooseEndingDirButton      matlab.ui.control.Button
        endDirField                matlab.ui.control.EditField
        BeamTrawlButton            matlab.ui.control.Button
        GenerateInfoTableButton    matlab.ui.control.Button
        ChooseSaveDirButton        matlab.ui.control.Button
        saveDirField               matlab.ui.control.EditField
        startDirField              matlab.ui.control.EditField
        LoadingProgressGaugeLabel  matlab.ui.control.Label
        LoadingProgressGauge       matlab.ui.control.Gauge
        selectedTable              matlab.ui.control.Table
        ChooseDataDirectoryButton  matlab.ui.control.Button
        dataDirField               matlab.ui.control.EditField
        doneField                  matlab.ui.control.EditField
        RunInfoTableGenerationToolLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function [datestr] = getDatestr(app,directory) 
            %extract the matlab datestring object from the starting
            %directories. NOTE: Relies on the current datescheme we have in the
            %citadel. Like so: F:\StrontiumData\2019\2019.12\12.16
            Year = str2num(directory(length(directory)-12:length(directory)-9));
            Month = str2num(directory(length(directory)-4:length(directory)-3));
            Day = str2num(directory(length(directory)-1:length(directory)));
        
            datestr = datetime(Year,Month,Day);
        end
        
        function [directory]  = getDirectory(app,datein,base_directory)
            %basically just the inverse of the above function, where I want to
            %output the string form of the directory given a datestring input
            %and the base_directory, which will change depending on which
            %computer this is run off of. 
            y = num2str(year(datein),'%04d');
            m = num2str(month(datein),'%02d');
            d = num2str(day(datein),'%02d');
            
        
            directory = string([base_directory filesep y filesep y '.' m filesep m '.' d]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseDataDirectoryButton
        function ChooseDataDirectoryButtonPushed(app, event)
            %gets the location of say SrData on the user's computer
            app.dataDirField.Value = uigetdir();
        end

        % Button pushed function: ChooseStartingDirButton
        function ChooseStartingDirButtonPushed(app, event)
            %choose starting day
            app.startDirField.Value = uigetdir(app.dataDirField.Value);
        end

        % Button pushed function: ChooseEndingDirButton
        function ChooseEndingDirButtonPushed(app, event)
            %choose ending day
            app.endDirField.Value = uigetdir(app.dataDirField.Value);
        end

        % Button pushed function: BeamTrawlButton
        function BeamTrawlButtonPushed(app, event)
            %get datestr object MATLAB (nice because doing +1 increments the day
            %without having to worry about getting months and years right)
            starting_directory = app.dataDirField.Value;
            tInit = getDatestr(app,app.startDirField.Value);
            tFin = getDatestr(app,app.endDirField.Value);
            %For each day get the collection of runs in that folder
            Tarray = tInit:tFin;
            string()
            run_titles = string([]);
            run_dates = NaT(0);
            for ii = Tarray
                %getting all of the files and subdirectories that are part of the
                %current directory
                subdirs = dir(getDirectory(app,ii,starting_directory));
                %then, using logical indexing, only take the ones that are actually
                %subdirectories
                ok_ones = logical([subdirs.isdir].*[~strcmp({subdirs.name},'.')].*[~strcmp({subdirs.name},'..')]);
                subdirs = subdirs(ok_ones);
                %append the run directory titles into the run_titles matrix. This is
                %going to be output as a CSV a little later in the process. Note I have
                %to do a bit of gymnastics with cells here to make sure that the
                %character arrays aren't concatenated before they are written into the
                %run_titles string array. 
                s = size(run_titles);
            %     keyboard;
                run_titles(1:(length(subdirs)+1),s(2)+1) = string([getDirectory(app,ii,starting_directory) string({subdirs.name})]);
                run_dates(1:(length(subdirs)+1),s(2)+1) = ii;
                app.LoadingProgressGauge.Value = 100*(find(Tarray==ii)/length(Tarray));
                drawnow;
            end
            
            run_titles = run_titles(2:end,1:end);
            run_dates = run_dates(2:end,1:end);
            %remove missing entries (these are there since not all days have the same
            %amount of runs taken.
            log_ind = ~run_titles.ismissing;
            run_titles = run_titles(log_ind);
            run_dates = run_dates(log_ind);
            run_dates.Format = 'dd-MMM-yyyy';
            include_bool = true(size(run_titles));
            
            %make a table object containing run information, and the option
            %to exclude certain runs
            tab = table(run_titles,run_dates,include_bool);
            app.selectedTable.Data = tab;
        end

        % Button pushed function: ChooseSaveDirButton
        function ChooseSaveDirButtonPushed(app, event)
            %choose where to save the runDataInfo
            app.saveDirField.Value = uigetdir(app.dataDirField.Value);
        end

        % Button pushed function: GenerateInfoTableButton
        function GenerateInfoTableButtonPushed(app, event)
            %from the uitable, extract the run info for the allowed runs
            tab = app.selectedTable.Data;
            %get the datetime list
            run_dates = tab.run_dates(tab.include_bool);
            %extract year, month day 
            year_col = year(run_dates);
            month_col = month(run_dates);
            day_col = day(run_dates);
            %extract titles
            run_titles = tab.run_titles(tab.include_bool);
            seriesID = strings(size(run_titles));
            runType = strings(size(run_titles));
            
            %the time range
            tInit = getDatestr(app,app.startDirField.Value);
            tFin = getDatestr(app,app.endDirField.Value);
            
            %get the data in outputabble form
            csv_output = [["Year";year_col] ["Month";month_col] ["Day";day_col] ["SeriesID";seriesID] ["RunType";runType] ["RunFolder";run_titles]];
            %save the output in datamanager format
            writematrix(csv_output,strcat(app.saveDirField.Value,filesep,string(tInit),'_',string(tFin),'RunInfoTable.csv'));
            %say that it's done
            app.doneField.Value = 'done';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChooseStartingDirButton = uibutton(app.UIFigure, 'push');
            app.ChooseStartingDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseStartingDirButtonPushed, true);
            app.ChooseStartingDirButton.Text = {'2: Choose Starting Dir'; '(Must be a day)'};
            app.ChooseEndingDirButton = uibutton(app.UIFigure, 'push');
            app.ChooseEndingDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseEndingDirButtonPushed, true);
            app.ChooseEndingDirButton.Text = {'3: Choose Ending Dir'; '(Must be a day)'};
            app.startDirField = uieditfield(app.UIFigure, 'text');
            app.startDirField.Editable = 'off';
            app.endDirField = uieditfield(app.UIFigure, 'text');
            app.endDirField.Editable = 'off';
            app.BeamTrawlButton = uibutton(app.UIFigure, 'push');
            app.BeamTrawlButton.ButtonPushedFcn = createCallbackFcn(app, @BeamTrawlButtonPushed, true);
            app.BeamTrawlButton.Text = '4: Beam Trawl!';
            app.GenerateInfoTableButton = uibutton(app.UIFigure, 'push');
            app.GenerateInfoTableButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateInfoTableButtonPushed, true);
            app.GenerateInfoTableButton.Text = {'6: Generate'; 'Info Table!'};
            app.ChooseSaveDirButton = uibutton(app.UIFigure, 'push');
            app.ChooseSaveDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseSaveDirButtonPushed, true);
            app.ChooseSaveDirButton.Text = '5: Choose Save Dir';
            app.saveDirField = uieditfield(app.UIFigure, 'text');
            app.saveDirField.Editable = 'off';
            app.LoadingProgressGaugeLabel = uilabel(app.UIFigure);
            app.LoadingProgressGaugeLabel.Text = {'Loading'; 'Progress'};
            app.LoadingProgressGauge = uigauge(app.UIFigure, 'circular');
            app.ChooseDataDirectoryButton = uibutton(app.UIFigure, 'push');
            app.ChooseDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataDirectoryButtonPushed, true);
            app.ChooseDataDirectoryButton.Text = {'1: Choose Data Directory'; '(i.e. StrontiumData)'};
            app.dataDirField = uieditfield(app.UIFigure, 'text');
            app.dataDirField.Editable = 'off';
            app.selectedTable = uitable(app.UIFigure);
            app.selectedTable.ColumnName = {'Run Title'; 'Date'; 'Include?'};
            app.selectedTable.RowName = {};
            app.selectedTable.ColumnEditable = [false false true];
            app.doneField = uieditfield(app.UIFigure, 'text');
            app.doneField.Editable = 'off';
            app.RunInfoTableGenerationToolLabel = uilabel(app.UIFigure);
            app.RunInfoTableGenerationToolLabel.Text = 'RunInfo Table Generation Tool';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'This tool will help you easily create an appropriate info table for a set of runs of your choosing.'; 'The info table is used by dataManager and contains information about run parameters.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = auto_gen_info

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end